classdef app_begin < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DiabeticsTechnologyUIFigure  matlab.ui.Figure
        Panel2                       matlab.ui.container.Panel
        Image2                       matlab.ui.control.Image
        ContinueButton               matlab.ui.control.Button
        Label                        matlab.ui.control.Label
        TryagainButton               matlab.ui.control.Button
        Panel                        matlab.ui.container.Panel
        Image                        matlab.ui.control.Image
        ScanButton                   matlab.ui.control.Button
        InputEditField               matlab.ui.control.EditField
        BluNiLabel                   matlab.ui.control.Label
        Image4                       matlab.ui.control.Image
        Panel3                       matlab.ui.container.Panel
        Image3                       matlab.ui.control.Image
        WaitingforresultsLabel       matlab.ui.control.Label
    end

    
    properties (Access = private)
        Main_app 
        ChPrivada
        User
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, calling_app)
            
            
            addpath("imagens/");
            addpath("fingerprint_ecriptacao_bd/BD_PC/");
            addpath("fingerprint_ecriptacao_bd/");
            %app.Image.ImageSource ='imagens/6.jpeg';
            app.Panel.BorderType = 'none';
            app.Panel2.BorderType = 'none';
            app.Panel2.Visible = 'off';
            app.Panel3.Visible = 'off';
            app.TryagainButton.Visible = 'off';
            movegui(app.DiabeticsTechnologyUIFigure,"center");
            
            app.ChPrivada = loadChavePrivada('PC',1);   % vai buscar a chave privada


        end

        % Button pushed function: ScanButton
        function ScanButtonPushed(app, event)
            
            pass = app.InputEditField.Value;
            pass = str2double(pass);
            app.ScanButton.Enable = 'off';
            pause(1);
            

            app.Panel.Visible = 'off';
            app.Panel3.Visible = 'on';
            pause(3);
            app.User = chavePrivada(pass,1);
            app.Panel3.Visible = 'off';
            app.Panel2.Visible = 'on';
            
            [info, res] = existeBD(app.User);
            
            %~strcmp(app.User, app.Privada)
            
            if ~res
                app.Image2.ImageSource ='rep.jpg';
                app.ContinueButton.Visible = 'off';
                app.TryagainButton.Visible = 'on';
                app.Label.Text = "You are not authorized" + newline + "to use the system!";
                app.ScanButton.Enable = 'on';
            else
                app.ContinueButton.Visible = 'on';
                app.TryagainButton.Visible = 'off';
                app.Image2.ImageSource ='68.jpg';
                app.Label.Text = ['Welcome to BluNi ', char newline, info.utilizador_p.nome];
            end
            
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            
                app.Main_app = main(app,app.ChPrivada,app.User);
                delete(app);
            
        end

        % Button pushed function: TryagainButton
        function TryagainButtonPushed(app, event)
            
            app.Panel2.Visible = 'off';
            app.Panel.Visible = 'on';
            app.InputEditField.Value = '';
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DiabeticsTechnologyUIFigure = uifigure('Visible', 'off');
            app.DiabeticsTechnologyUIFigure.Name = 'Diabetics Technology';
            app.Panel2 = uipanel(app.DiabeticsTechnologyUIFigure);
            app.Image2 = uiimage(app.Panel2);
            app.Image2.ImageSource = '68.jpg';
            app.ContinueButton = uibutton(app.Panel2, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            app.Label = uilabel(app.Panel2);
            app.TryagainButton = uibutton(app.Panel2, 'push');
            app.TryagainButton.ButtonPushedFcn = createCallbackFcn(app, @TryagainButtonPushed, true);
            app.TryagainButton.Text = 'Try again';
            app.Panel = uipanel(app.DiabeticsTechnologyUIFigure);
            app.Image = uiimage(app.Panel);
            app.Image.ImageSource = 'bkjlkhbj.gif';
            app.ScanButton = uibutton(app.Panel, 'push');
            app.ScanButton.ButtonPushedFcn = createCallbackFcn(app, @ScanButtonPushed, true);
            app.ScanButton.Text = 'Scan';
            app.InputEditField = uieditfield(app.Panel, 'text');
            app.BluNiLabel = uilabel(app.Panel);
            app.BluNiLabel.Text = 'BluNi';
            app.Image4 = uiimage(app.Panel);
            app.Image4.ImageSource = 'dhf.gif';
            app.Panel3 = uipanel(app.DiabeticsTechnologyUIFigure);
            app.Image3 = uiimage(app.Panel3);
            app.Image3.ImageSource = 'ex1.gif';
            app.WaitingforresultsLabel = uilabel(app.Panel3);
            app.WaitingforresultsLabel.Text = 'Waiting for results ...';
            % Show the figure after all components are created
            app.DiabeticsTechnologyUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_begin(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DiabeticsTechnologyUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DiabeticsTechnologyUIFigure)
        end
    end
end