classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        HomeMenu                       matlab.ui.container.Menu
        UsersMenu                      matlab.ui.container.Menu
        MedicalRecordMenu              matlab.ui.container.Menu
        DevicesMenu                    matlab.ui.container.Menu
        HistoryMenu                    matlab.ui.container.Menu
        TabGroup                       matlab.ui.container.TabGroup
        MainTab                        matlab.ui.container.Tab
        BloodGlucoseLevelsLabel        matlab.ui.control.Label
        UIAxes                         matlab.ui.control.UIAxes
        AverageEditFieldLabel          matlab.ui.control.Label
        AverageEditField               matlab.ui.control.NumericEditField
        LatestValueEditField           matlab.ui.control.NumericEditField
        LatestValueEditFieldLabel      matlab.ui.control.Label
        Image8                         matlab.ui.control.Image
        LogoutButton                   matlab.ui.control.Button
        Back_panelTab                  matlab.ui.container.Tab
        BackPanelLabel                 matlab.ui.control.Label
        ComunicationPanel              matlab.ui.container.Panel
        UITable                        matlab.ui.control.Table
        PrivateKeyLabel                matlab.ui.control.Label
        PrivateKeyEditField            matlab.ui.control.EditField
        PumpPublicKeyEditFieldLabel    matlab.ui.control.Label
        PumpPublicKeyEditField         matlab.ui.control.EditField
        MessageListLabel               matlab.ui.control.Label
        UITable2                       matlab.ui.control.Table
        UITable2_2                     matlab.ui.control.Table
        SensorPublicKeyEditFieldLabel  matlab.ui.control.Label
        SensorPublicKeyEditField       matlab.ui.control.EditField
        ListBox                        matlab.ui.control.ListBox
        ListBox_2                      matlab.ui.control.ListBox
        DecryptedMessageLabel          matlab.ui.control.Label
        EncryptedMessageLabel          matlab.ui.control.Label
        Tree                           matlab.ui.container.Tree
        CommunicationNode              matlab.ui.container.TreeNode
        SensorNode                     matlab.ui.container.TreeNode
        ReceiveNode                    matlab.ui.container.TreeNode
        SentNode                       matlab.ui.container.TreeNode
        InsulinPumpNode                matlab.ui.container.TreeNode
        ReceiveNode_2                  matlab.ui.container.TreeNode
        SentNode_2                     matlab.ui.container.TreeNode
        Image                          matlab.ui.control.Image
        UhOhLabel                      matlab.ui.control.Label
        MessageLabel                   matlab.ui.control.Label
        Image10                        matlab.ui.control.Image
        FreezeapplicationSwitchLabel   matlab.ui.control.Label
        FreezeapplicationSwitch        matlab.ui.control.Switch
        Personal_dataTab               matlab.ui.container.Tab
        PersonalDataLabel              matlab.ui.control.Label
        NameEditFieldLabel             matlab.ui.control.Label
        NameEditField                  matlab.ui.control.EditField
        BirthdayEditFieldLabel         matlab.ui.control.Label
        BirthdayEditField              matlab.ui.control.EditField
        EmailEditFieldLabel            matlab.ui.control.Label
        EmailEditField                 matlab.ui.control.EditField
        ContactEditFieldLabel          matlab.ui.control.Label
        ContactEditField               matlab.ui.control.EditField
        Image4                         matlab.ui.control.Image
        PrimaryuserLabel               matlab.ui.control.Label
        PermissionsLabel_2             matlab.ui.control.Label
        Image5                         matlab.ui.control.Image
        Image6                         matlab.ui.control.Image
        Image7                         matlab.ui.control.Image
        GuilhermeRamosLabel            matlab.ui.control.Label
        PatrciaSoaresLabel             matlab.ui.control.Label
        GonaloBorgesLabel              matlab.ui.control.Label
        NameEditField_2                matlab.ui.control.EditField
        ContactEditField_2             matlab.ui.control.EditField
        RelationEditField              matlab.ui.control.EditField
        NameLabel                      matlab.ui.control.Label
        RelationLabel                  matlab.ui.control.Label
        ContactLabel                   matlab.ui.control.Label
        Medical_recordTab              matlab.ui.container.Tab
        CarbohydratesEditFieldLabel    matlab.ui.control.Label
        CarbohydratesEditField         matlab.ui.control.NumericEditField
        SendUpdateButton               matlab.ui.control.Button
        MedicalRecommendationsforInsulinInjectionLabel  matlab.ui.control.Label
        TabGroup2                      matlab.ui.container.TabGroup
        BasalInjectionsTab             matlab.ui.container.Tab
        UITable3                       matlab.ui.control.Table
        DoseTimeLabel                  matlab.ui.control.Label
        DoseTimeEditField              matlab.ui.control.EditField
        InsulinunitsLabel              matlab.ui.control.Label
        InsulinunitsEditField          matlab.ui.control.EditField
        ChangeButton                   matlab.ui.control.Button
        BolusInjectionsTab             matlab.ui.container.Tab
        ChangeButton_2                 matlab.ui.control.Button
        DoseTimeEditField_2Label       matlab.ui.control.Label
        DoseTimeEditField_2            matlab.ui.control.EditField
        InsulinunitsEditField_2Label   matlab.ui.control.Label
        InsulinunitsEditField_2        matlab.ui.control.EditField
        UITable3_2                     matlab.ui.control.Table
        MedicalstatementsLabel         matlab.ui.control.Label
        MinimumGlucoseLevelEditFieldLabel  matlab.ui.control.Label
        MinimumGlucoseLevelEditField   matlab.ui.control.EditField
        MaximumGlucoseLevelEditFieldLabel  matlab.ui.control.Label
        MaximumGlucoseLevelEditField   matlab.ui.control.EditField
        InsulinsensitivityfactorEditFieldLabel  matlab.ui.control.Label
        InsulinsensitivityfactorEditField  matlab.ui.control.EditField
        ChangeButton_3                 matlab.ui.control.Button
        MedicalRecordsLabel            matlab.ui.control.Label
        DevicesTab                     matlab.ui.container.Tab
        ConnectingDevicesLabel         matlab.ui.control.Label
        CommunicationwithInsulinPumpPanel  matlab.ui.container.Panel
        Image2_2                       matlab.ui.control.Image
        SystemStateLabel_2             matlab.ui.control.Label
        Image9_2                       matlab.ui.control.Image
        DepositLevelGauge_2Label       matlab.ui.control.Label
        DepositLevelGauge_2            matlab.ui.control.LinearGauge
        LatestadministeredInsulindoseEditFieldLabel  matlab.ui.control.Label
        LatestadministeredInsulindoseEditField  matlab.ui.control.NumericEditField
        CommunicationwithSensorPanel   matlab.ui.container.Panel
        DefinethenewDateDatePickerLabel  matlab.ui.control.Label
        DefinethenewDateDatePicker     matlab.ui.control.DatePicker
        Image2                         matlab.ui.control.Image
        HowmanydaysleftEditFieldLabel  matlab.ui.control.Label
        HowmanydaysleftEditField       matlab.ui.control.NumericEditField
        SystemStateLabel               matlab.ui.control.Label
        Image9                         matlab.ui.control.Image
        ActivateDevicesButton          matlab.ui.control.Button
        HistoryTab                     matlab.ui.container.Tab
        TituloGraphLabel               matlab.ui.control.Label
        UIAxes2                        matlab.ui.control.UIAxes
        StartEditFieldLabel            matlab.ui.control.Label
        StartEditField                 matlab.ui.control.NumericEditField
        EditField_2                    matlab.ui.control.NumericEditField
        Label                          matlab.ui.control.Label
        EditField_3                    matlab.ui.control.NumericEditField
        Label_2                        matlab.ui.control.Label
        FinishEditFieldLabel           matlab.ui.control.Label
        FinishEditField                matlab.ui.control.NumericEditField
        DefineanIntervalLabel          matlab.ui.control.Label
        ZoominButton                   matlab.ui.control.Button
        AverageEditField_2Label        matlab.ui.control.Label
        AverageEditField_2             matlab.ui.control.NumericEditField
        GraphTypeDropDownLabel         matlab.ui.control.Label
        GraphTypeDropDown              matlab.ui.control.DropDown
        PeakvaluesLabel                matlab.ui.control.Label
        MaximumEditField_2Label        matlab.ui.control.Label
        MaximumEditField_2             matlab.ui.control.NumericEditField
        MinimumEditField_2Label        matlab.ui.control.Label
        MinimumEditField_2             matlab.ui.control.NumericEditField
        ZoomoutButton                  matlab.ui.control.Button
    end

    
    properties (Access = private)
        Calling_app         % Start
        Chave_Privada       % Chave Privada para a comunicação
        Chave_Publica_Sensor% Chave Publica para a comunicação com o sensor
        Chave_Publica_Bomba % Chave Publica para a comunicação com a bomba
        Flag_bluetooth      % Indicador se as ligações foram estabelecidas
        Corrent_User        % Informação do utilizador atual
        Main_User           % Informação do utilizador principal
        Sub_page            % Como abrir uma nova window
        ReadTimer           % Timer object
        PlotLine            % Line object
        Topics              % Cabeçalhos das comunicações bluetooth
        Bomba_app_values    % Conteudo recebido pela bomba
        Sensor_app_values   % Conteudo recebido pelo sensor
        Graph_insulina      % Guarda os valores da insulina
        Graph_glicemia      % Guarda os valores Glicemicos
        Graph_hc            % Guarda os valores dos HC
        indices_bolus       % Indices da tabela bolus
        indices_basal       % Indices da tabela basal
        Renovar_sensor      % Indicador se o sensor deve ser renovado
        Tab_sensor_receive  % Todas as mensagens recebidas do sensor
        Tab_sensor_send     % Todas as mensagens enviadas ao sensor
        Tab_pump_receive    % Todas as mensagens recebidas da bomba
        Tab_pump_send       % Todas as mensagens enviadas para a bomba
        Lista_msg           % Guarda a lista de todas as mensagens trocadas entre dispositivos
        Overall_msg         % Guarda a informação macro de toda a comunicação a nível de mensagens
        Overall_system      % Guarda a informação macro de toda a comunicação a nível de token e simulações  
        Flag_graphs         % Indica que gráficos mostrar no painel História
        zoom_graph          % Variável que guarda o gráfico em formato zoom
        zoom_on             % Flag que identifica se estamos em estado de zoom ou não
        tempo_aux1          % Auxiliar para indicar o intervalo inferior de tempo (XLim) durante a fase inicial na Main
        tempo_aux2          % Auxiliar para indicar o intervalo superior de tempo (XLim) durante a fase inicial na Main
        Ack_sensor          % Flag auxiliar que determina quando devemos esperar pelo acknowledge do sensor
        Ack_bomba           % Flag auxiliar que determina quando devemos esperar pelo acknowledge da bomba
        Debug_sensor        % Identifica a origem de onde veio o erro da falta de mensagens do sensor
        Debug_bomba         % Identifica a origem de onde veio o erro da falta de mensagens da bomba
        Max_erros           % Assinala um excesso de erros na comunicação
        
        % Propriedades auxiliares para a situação sem comunicação:
        GG_aux
        GI_aux
        i

        
    end
    
    methods (Access = private)
    
       function BluetoothTimerFcn(app,~,~)

            %% *********************** CONTROLO DAS MENSAGENS POR BLUETOOTH ******************************     
            
           % Ver se existem mensagens recebidas da comunicação bluetooth:
            app.Bluetooth_receive_values();

            
            if any(strcmp(app.Topics,"ack"))
                               
                if ~(isempty(app.Sensor_app_values))
                    app.Ack_sensor = false;
                end
                
                
                if ~(isempty(app.Bomba_app_values))
                    app.Ack_bomba = false;  
                end 
                
            end             

            
            if app.Ack_sensor
                app.Ack_sensor = false; 
                topic = "ack" + app.Debug_sensor;
                app.Bluetooth_errors("sr","1",topic);
                
            end
            
            
            if app.Ack_bomba
                app.Ack_bomba = false;
                topic = "ack" + app.Debug_bomba;
                app.Bluetooth_errors("ir","1",topic);
               
            end             
            
            
            if any(strcmp(app.Topics,"simulacao")) && app.Flag_bluetooth(1)
                
                app.Bluetooth_transmit_values("ss", "ack", "simulacao");
                
                if app.Sensor_app_values(3)
                    num = app.Overall_system{1,2};
                else
                    num = string(str2num(app.Overall_system{1,2})+1);
                end
                
                app.Overall_system = table(app.Sensor_app_values(2),num,app.Overall_system{1,3},app.Overall_system{1,4},'VariableNames',{'ID_simul','Num_simul','Time_limit','Num_renova'});
                
            end
            
            
            if any(strcmp(app.Topics,"autenticacao"))
                
                
                if str2double(app.Sensor_app_values) == app.Flag_bluetooth(2)
                    app.Flag_bluetooth(1) = true;
                    app.Bluetooth_transmit_values("ss", "ack", "autenticacao");
                end                
             
                
                if str2double(app.Bomba_app_values) == app.Flag_bluetooth(4)
                    app.Flag_bluetooth(3) = true;
                    app.Bluetooth_transmit_values("is", "ack", "autenticacao");
                end
                
                
                if app.Flag_bluetooth(1) || app.Flag_bluetooth(3)
                    
                    % Mostrar que a ligação bluetooth foi estabelecida com sucesso para os 2 dispositivos 
                    app.Image10.ImageSource = "chnv.gif";
                    app.UhOhLabel.Text = "Ready!";
                    app.MessageLabel.Text = "Connection established" + newline + "between devices!";
                    app.Tree.Enable = 'on';
                    app.GraphTypeDropDown.Enable = 'on';
                    app.ZoominButton.Enable = 'on';
                    app.ZoomoutButton.Enable = 'on';
                    app.SendUpdateButton.Enable = 'on'; 
                    app.FreezeapplicationSwitch.Enable = 'on';
                    app.ActivateDevicesButton.Enable = 'off';
                    app.LogoutButton.Enable = 'on';
                    
                end

                
            end
           
            
            if any(strcmp(app.Topics,"glicemia")) % TEMPORÁRIO: & app.Flag_bluetooth
                 
                app.Bluetooth_transmit_values("ss", "ack", "glicemia"); 
                
                % Update YData no plot do painel principal:    
                y = str2double(app.Sensor_app_values(2));
                ydata = app.PlotLine.YData;
                xdata = app.PlotLine.XData;
                ydata = circshift(ydata,-1);
                xdata = circshift(xdata,-1);
                ydata(end) = y;
                xdata(end) = xdata(end-1) + minutes(5);
                app.PlotLine.YData = ydata;
                app.PlotLine.XData = xdata;           
                
                if (xdata(end)> app.tempo_aux2)
                    app.UIAxes.XLim = [xdata(1) xdata(end)];
                else
                    app.UIAxes.XLim = [app.tempo_aux1 app.tempo_aux2];
                end
     
                hold(app.UIAxes,'on');
                if  ydata(end) < app.Main_User.tab_infoMedico.g_min || ydata(end) > app.Main_User.tab_infoMedico.g_max
                    plot(app.UIAxes,xdata(end),ydata(end), 'MarkerEdgeColo',[0, 0, 0], 'MarkerSize',9, 'Marker', 'o','MarkerFaceColor',[1, 0, 0]);
                    app.LatestValueEditField.Value = ydata(end);
                    app.LatestValueEditField.BackgroundColor = [1, 0, 0];
                    app.LatestValueEditFieldLabel.Text = 'Critical Value';
                else
                    plot(app.UIAxes,xdata(end),ydata(end), 'MarkerEdgeColo',[0 0.7 0.8], 'MarkerSize',9, 'Marker', 'o');
                end
                
                
                % Determinar o valor que vai aparecer (e a sua cor) nas
                % caixas de texto "Average" e "Latest Value"
                 
                app.AverageEditField.Value = sum(ydata)/length(ydata);
                
                if strcmp(app.LatestValueEditFieldLabel.Text,'Critical Value')
                    
                    if ~ismember(app.LatestValueEditField.Value,ydata)
                        app.LatestValueEditField.Value = ydata(end);
                        app.LatestValueEditFieldLabel.Text = 'Latest Value';
                        app.LatestValueEditField.BackgroundColor = [1, 1, 1];
                    end
                    
                else
                    app.LatestValueEditField.Value = ydata(end);
                end
                
                
                % Guardar o novo valor glicémico na tabela "Graph_glicemia"
                
                aux = table(y,xdata(end),'VariableNames',{'valor','data'});
                app.Graph_glicemia = [app.Graph_glicemia; aux];


                % Indicar o consumo de zero calorias no "Graph_hc" se necessário
                
                aux_data = app.Graph_hc{end,2};
                
                if aux_data < xdata(end)
                    aux = table(0.0,xdata(end),'VariableNames',{'valor','data'});
                    app.Graph_hc = [app.Graph_hc; aux];                    
                end
                
                
                % Indicar o caso em que não houve administração de insulina no "Graph_insulina" 
                
                if ~any(strcmp(app.Topics,"insulina"))
                    
                    % TEMPORÁRIAMENTE em vez de y = 0.0 ser y = GI(i)
                    y = app.GI_aux{app.i, 1}; 
                    
                    aux = table(y,xdata(end),'VariableNames',{'valor','data'});
                    app.Graph_insulina = [app.Graph_insulina; aux];                    
                end 
                
                aux2 = (datetime(app.Main_User.tab_insulinaBasal.Time,'InputFormat', 'HH:mm') == xdata(end));
                aux3 = app.Main_User.tab_insulinaBasal.Insulin_units(aux2);
                
                if ~(isempty(aux3))
                    app.Bluetooth_transmit_values("is", "basal", aux3);
                    app.Ack_bomba = true;
                    app.Debug_bomba = " from basal";
                end
            
            end
     
            
            if any(strcmp(app.Topics,"deposito")) && app.Flag_bluetooth(3)
                            
                if(app.Bomba_app_values(2) < 10)
                    app.SystemStateLabel_2.Text = "Deposit" + newline + "Warning!";
                    app.Image8.Visible = 'on';  
                    app.Image9_2.ImageSource = 'source.gif'; 
                    
                end
                
                app.DepositLevelGauge_2.Value = str2double(app.Bomba_app_values(2));
                
                % Encriptar + Guardar + Enviar a mensagem de ack 
                app.Bluetooth_transmit_values("is", "ack", "deposito");  
                
            end 


            if any(strcmp(app.Topics,"insulina")) && app.Flag_bluetooth(3)
               

                % Guardar o novo valor de insulina na tabela "Graph_insulina"
                app.LatestadministeredInsulindoseEditField.Value = app.Bomba_app_values(2);                
                y = str2double(app.Bomba_app_values(2));
                x = app.Graph_glicemia{end,2};
                aux = table(y,x,'VariableNames',{'valor','data'});
                app.Graph_insulina = [app.Graph_insulina; aux];
                
                
                % Encriptar + Guardar + Enviar a mensagem de ack:
                app.Bluetooth_transmit_values("is", "ack", "insulina"); 
                
                
            end            
            
            
            %% *************************** AÇÕES DE VERIFICAÇÃO **********************************
            
            % Avisar o utilizador da ocorrência de algo:
            if strcmp(app.Image9.ImageSource,'source.gif') || strcmp(app.Image9_2.ImageSource,'source.gif')
                app.Image8.Visible = 'on';
            end
            
            % Atualizar o número de dias que falta para renovar o sensor
            if ~(app.Renovar_sensor == datetime('now').Day)
                app.DefinethenewDateDatePickerValueChanged();
                
            end
            
            
            % Coloca um limite de tempo para as chaves publicas utilizadas
            % nas comunicações. Assim que o "token" expirar ele é renovado 
            % (tempo máximo de 5 horas):
            %if (app.Flag_bluetooth(1) && app.Flag_bluetooth(3))  && (datetime(app.Overall_system{1,3}) < datetime('now'));
            %    app.ActivateDevicesButtonPushed();
            %end
            
            
            % Desenhar os gráficos no Painel História:
            app.UIAxes2.cla;
            app.MaximumEditField_2.Value = 0;
            app.MinimumEditField_2.Value = 0;
            app.AverageEditField_2.Value = 0;


            if any(app.Flag_graphs)

                if app.Flag_graphs(3)
                    
                    app.TituloGraphLabel.Text = "Carbohydrates Graph";
                    app.UIAxes2.YLim = [0 300];
                    if ~(app.zoom_on)                        
                        barra = bar(app.UIAxes2,app.Graph_hc.data,app.Graph_hc.valor,'r'); 
                        app.MaximumEditField_2.Value = max(app.Graph_hc.valor);
                        app.MinimumEditField_2.Value = min(app.Graph_hc.valor);
                        app.AverageEditField_2.Value = sum(app.Graph_hc.valor)/length(app.Graph_hc.valor);
                    else
                        barra = bar(app.UIAxes2,app.zoom_graph.data,app.zoom_graph.valor,'r');
                        app.MaximumEditField_2.Value = max(app.zoom_graph.valor);
                        app.MinimumEditField_2.Value = min(app.zoom_graph.valor);
                        app.AverageEditField_2.Value = sum(app.zoom_graph.valor)/length(app.zoom_graph.valor);
                    end  
                    
                    barra.BarWidth = 2;
                    barra.EdgeColor = 'black';
                    hold(app.UIAxes2,'on');                
                
                elseif app.Flag_graphs(1)
                    
                    app.TituloGraphLabel.Text = "Glycemic Graph";
                    app.UIAxes2.YLim = [40 210];
                    
                    if ~(app.zoom_on)                        
                        plot(app.UIAxes2,app.Graph_glicemia.data,app.Graph_glicemia.valor, 'MarkerEdgeColo',[0, 0, 0], 'MarkerSize',5, 'Marker', 'o','MarkerFaceColor',[1, 0, 0], 'Color', [0 0.7 0.8]);     
                        app.MaximumEditField_2.Value = max(app.Graph_glicemia.valor);
                        app.MinimumEditField_2.Value = min(app.Graph_glicemia.valor);
                        app.AverageEditField_2.Value = sum(app.Graph_glicemia.valor)/length(app.Graph_glicemia.valor);
                    else
                        plot(app.UIAxes2,app.zoom_graph.data,app.zoom_graph.valor, 'MarkerEdgeColo',[0, 0, 0], 'MarkerSize',5, 'Marker', 'o','MarkerFaceColor',[1, 0, 0], 'Color', [0 0.7 0.8]); 
                        app.MaximumEditField_2.Value = max(app.zoom_graph.valor);
                        app.MinimumEditField_2.Value = min(app.zoom_graph.valor);
                        app.AverageEditField_2.Value = sum(app.zoom_graph.valor)/length(app.zoom_graph.valor);
  
                    end
                    
                    hold(app.UIAxes2,'on');
               
                else app.Flag_graphs(2)
                    
                    app.TituloGraphLabel.Text = "Insulin Graph";
                    app.UIAxes2.YLim = [0 30];
                    
                    if ~(app.zoom_on)                        
                        zona = area(app.UIAxes2,app.Graph_insulina.data,app.Graph_insulina.valor); 
                        app.MaximumEditField_2.Value = max(app.Graph_insulina.valor);
                        app.MinimumEditField_2.Value = min(app.Graph_insulina.valor);
                        app.AverageEditField_2.Value = sum(app.Graph_insulina.valor)/length(app.Graph_insulina.valor);
                                                 
                    else
                        zona = area(app.UIAxes2,app.zoom_graph.data,app.zoom_graph.valor);  
                        app.MaximumEditField_2.Value = max(app.zoom_graph.valor);
                        app.MinimumEditField_2.Value = min(app.zoom_graph.valor);
                        app.AverageEditField_2.Value = sum(app.zoom_graph.valor)/length(app.zoom_graph.valor);
        
                    end
                    
                    zona.FaceColor = [0 0.75 0.75];
                    zona.FaceAlpha = 0.5;
                    hold(app.UIAxes2,'on');
                end 
                
                hold(app.UIAxes2,'off');
                
            end
            
            % Verificar se é necessário fechar as comunicações entre
            % dispositivos devido a um excesso de erros:
            if app.Max_erros > 8
                
                app.Image10.Visible = 'on';
                app.UhOhLabel.Visible = 'on';
                app.MessageLabel.Visible = 'on';
                app.Image10.ImageSource = "xxx.gif";
                app.UhOhLabel.Text = "Uh-Oh!";
                app.MessageLabel.Text = "No Bluetooth connection" + newline + "between devices!"; 
                app.ComunicationPanel.Visible = 'off';
                app.Image.Visible = 'off';            
                app.GraphTypeDropDown.Enable = 'off';
                app.ZoominButton.Enable = 'off';
                app.ZoomoutButton.Enable = 'off';
                app.SendUpdateButton.Enable = 'off';
                app.Tree.Enable = 'off'; 
                app.FreezeapplicationSwitch.Enable = 'off';
                stop(app.ReadTimer);
                app.Max_erros = 0;
                app.ActivateDevicesButton.Enable = 'on';
                
            end            

                      
       end        
        
        
        function ploting(app, x, y)
            
            app.PlotLine = plot(app.UIAxes,x,y);
            app.PlotLine.Color = [0 0.7 0.8];
            app.PlotLine.Marker = 'o';
            app.PlotLine.MarkerEdgeColor = [0,0,0];
            app.PlotLine.MarkerSize = 9;
            app.PlotLine.LineWidth = 1.1;
            app.tempo_aux1 = datetime('08:00','InputFormat','HH:mm');
            app.tempo_aux1.Format = 'HH:mm';
            app.tempo_aux2 = datetime('08:45','InputFormat','HH:mm');
            app.tempo_aux2.Format = 'HH:mm';
            app.UIAxes.XLim = [app.tempo_aux1 app.tempo_aux2];

        end
        
        
        function Bluetooth_receive_values(app)

            espera_sensor = 1;
            espera_bomba = 1;
            
            if app.Ack_sensor
                espera_sensor = 2;
            end
                
            if app.Ack_bomba
                espera_bomba = 2;
            end

            % Utilizar as funções para ler resultado da comunicação Bluetooth:
            output1 = recetor("bomba_app",espera_bomba);
            output2 = recetor("sensor_app",espera_sensor);
            
            app.Topics = [];
            app.Sensor_app_values = [];
            app.Bomba_app_values = [];
            
            % TEMPORÁRIO:
            app.i = app.i + 1;

            
            if isempty(output1) && isempty(output1)
                
                % Deveria ser (para identificar com isempty(...) && isempty(...)): 
                % MUDAR ISTO!!!!
                % app.Topics = [];
                % app.Sensor_app_values = [];
                % app.Bomba_app_values = [];
                
                % TEMPORÁRIO:                
                y = app.GG_aux{app.i, 1};
                y = num2str(y);
                
                encrip_info_sensor = encripta(app.Chave_Privada, app.Chave_Publica_Sensor,y);
                encrip_assunto_sensor = encripta(app.Chave_Privada, app.Chave_Publica_Sensor, "glicemia");   
                encrip_falhas_sensor = encripta(app.Chave_Privada, app.Chave_Publica_Sensor, "0"); 
                
                output = [encrip_assunto_sensor,encrip_falhas_sensor,encrip_info_sensor];

                output = app.Bluetooth_decript_save_values("sr", output);
                app.Topics = output(1);
                app.Sensor_app_values = output(2:end);
                
            end
            
            if ~isempty(output1)  
                
                % BOMBA - PODEMOS RECEBER INFORMAÇÃO SOBRE:
                % 1 -> "insulina" + Valor administrado + hora
                % 2 -> "deposito" + Nível do deposito de insulina
                % 3 -> "autenticacao" + Sucesso/Insucesso na iniciação  
                % 4 -> "ack" + Assunto
                
                output1 = app.Bluetooth_decript_save_values("ir", output1);
                
                app.Topics = [app.Topics,output1(1)];
                app.Bomba_app_values = output1(2:end);
                
                
            end
            
            if ~isempty(output2)
                
                % SENSOR - PODEMOS RECEBER INFORMAÇÃO SOBRE:
                % 1 -> "autenticacao" + Resultado do teste
                % 2 -> "simulacao" + nº de falhas + ID da simulação + Inicio ou Fim da Simulação (true / false)
                % 3 -> "indice glicemico" + nº de falhas + Valor do indice glicemico
                % 4 -> "ack" + Assunto
                
                output2 = app.Bluetooth_decript_save_values("sr", output2);
                
                app.Topics = [app.Topics,output2(1)];
                app.Sensor_app_values = output2(2:end);
                
            end           
      
            
        end
        
        function Bluetooth_transmit_values(app, type, header, body)
            
            % Esta função vai transmitir uma mensagem ou para o sensor ou
            % para a bomba (conforme o type). Primeiro encripta, depois
            % envia e, por fim, mantem um registo desta operação.
            
            if type == "ss"
                ficheiro = "app_sensor";
                assunto = encripta(app.Chave_Privada,app.Chave_Publica_Sensor,header);
                valor = encripta(app.Chave_Privada,app.Chave_Publica_Sensor,body);
                
            else
                ficheiro = "app_bomba";
                assunto = encripta(app.Chave_Privada,app.Chave_Publica_Bomba,header);
                valor = encripta(app.Chave_Privada,app.Chave_Publica_Bomba,body);
                
            end

            msg_decriptada = [header, body, "", "", ""];
            msg_encriptada = [assunto, valor, "", "", ""]; 
            
            msg_size = [num2str(strlength(header)) ' x ' num2str(strlength(body))]; 
            msg_size = string(msg_size);

            time = datetime('now');
            time = string(datestr(time,"HH:MM"));            
            
            aux1 = table(type,string(str2num(app.Lista_msg{end,2})+1),header,msg_size,time,msg_encriptada,msg_decriptada,'VariableNames',{'Tipo','ID','Assunto','Tamanho','Time','Msg_encript', 'Msg_decript'});
            app.Lista_msg = [app.Lista_msg; aux1];
            
            app.Overall_msg = table(app.Lista_msg{end,2},app.Overall_msg{end,2},app.Overall_msg{end,3},app.Lista_msg{end,5},'VariableNames',{'Num_msg','Time_inicio','Num_falhas','Time_ultima'});     
            
            msg_encriptada = [assunto, valor];
            
            %transmissor(ficheiro,msg_encriptada);              
            
            
        end
        
        
        function msg = Bluetooth_decript_save_values(app, type, values_encripted)
            
            % Esta função vai decriptar toda a informação que foi recebida
            % (seja da bomba ou do sensor, conforme o type), devolve a mensagem 
            % decriptada e guarda um registo dete evento.
            
            values_decripted = [];
            num = size(values_encripted);
            num = num(2);
            
            if type == "sr"
                
                for j = 1:num
                    
                    aux = decripta(app.Chave_Privada,app.Chave_Publica_Sensor,values_encripted(j));
                    values_decripted = [values_decripted aux]; 
                    
                    if j == 1
                        msg_size = [num2str(strlength(values_decripted(end)))];
                    else
                        msg_size = [msg_size ' x ' num2str(strlength(values_decripted(end)))];
                    end                    
                    
                end
                
                if ~(values_decripted(2) == "0") & ~(strcmp(values_decripted(1),'autenticacao') | strcmp(values_decripted(1),'ack'))
                    
                    app.Bluetooth_errors("ss",values_decripted(2),values_decripted(1));
                  
                end                
                

            else
                
                for j = 1:num
                    
                    aux = decripta(app.Chave_Privada,app.Chave_Publica_Bomba,values_encripted(j));
                    values_decripted = [values_decripted aux]; 
                    
                    if j == 1
                        msg_size = [num2str(strlength(values_decripted(end)))];
                    else
                        msg_size = [msg_size ' x ' num2str(strlength(values_decripted(end)))];
                    end   
                end  
                
                                
                if ~(values_decripted(2) == "0") & ~(strcmp(values_decripted(1),'autenticacao') | strcmp(values_decripted(1),'ack'))
                    
                    app.Bluetooth_errors("is",values_decripted(2),values_decripted(1));
                  
                end 
                
            end 
            
            msg = values_decripted;
            
            if ~(num == 5)
                for k = 1:(5 - num)
                    values_encripted = [values_encripted ""];
                    values_decripted = [values_decripted ""];
                end
            end
            
            msg_size = string(msg_size);

            time = datetime('now');
            time = string(datestr(time,"HH:MM"));            
            
            aux1 = table(type,string(str2num(app.Lista_msg{end,2})+1),values_decripted(1),msg_size,time,values_encripted,values_decripted,'VariableNames',{'Tipo','ID','Assunto','Tamanho','Time','Msg_encript', 'Msg_decript'});
            app.Lista_msg = [app.Lista_msg; aux1];
            
            app.Overall_msg = table(app.Lista_msg{end,2},app.Overall_msg{end,2},app.Overall_msg{end,3},app.Lista_msg{end,5},'VariableNames',{'Num_msg','Time_inicio','Num_falhas','Time_ultima'});     


        end
        
        
        function Bluetooth_errors(app, type, num, assunto)
            
            num = str2num(num);
            assunto = "Lost message: " + assunto;
            msg_encripted = ["~", "", "", "", ""];
            msg_decripted = [assunto, "", "", "", ""];

            for j = 1:num
                aux1 = table(type,string(str2num(app.Lista_msg{end,2})+1),"MISSING"," ~ "," ~ ",msg_encripted,msg_decripted,'VariableNames',{'Tipo','ID','Assunto','Tamanho','Time','Msg_encript', 'Msg_decript'});
                app.Lista_msg = [app.Lista_msg; aux1];  
                
            end    
            
            app.Overall_msg = table(app.Lista_msg{end,2},app.Overall_msg{end,2},string(str2num(app.Overall_msg{1,3})+ num),app.Overall_msg{end,4},'VariableNames',{'Num_msg','Time_inicio','Num_falhas','Time_ultima'});
            
            app.Max_erros = app.Max_erros + 1;
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, start_app, chave_privada, user)
            
            app.Chave_Privada = chave_privada;
            
            % Definir o Path:
            addpath("imagens/");
            addpath("fingerprint_ecriptacao_bd");
            addpath("fingerprint_ecriptacao_bd/BD/");
            addpath("fingerprint_ecriptacao_bd/BD_PC/");
            addpath("fingerprint_ecriptacao_bd/bluetooth/")
            
            
            % Posicionar a aplicação e definir a aparência inicial:
            app.UIFigure.Resize = 'off';
            movegui(app.UIFigure,"center");
            app.TabGroup.SelectedTab = app.MainTab;
            app.UIAxes.BackgroundColor = 'white';
            app.UIAxes2.BackgroundColor = 'white';
            app.UIAxes2.YLim = [40 210];
            
            
            % Variáveis auxiliares que permitem mostrar valores sem haver
            % comunicação entre dispositivos:  
            load("fingerprint_ecriptacao_bd/BD/GG.mat");
            load("fingerprint_ecriptacao_bd/BD/GI.mat");
            app.GG_aux = GG;
            app.GI_aux = GI;
            app.i = 0;
            
            
            % Inicializar variáveis para conseguir alterar as tabelas
            % (bolus e basal) com a informação médica  
            app.indices_bolus = [1 1];
            app.indices_basal =[1 1];
            
            
            % Inicializar as variáveis que guardam toda a informação dos
            % gráficos de glicemia, insulina e calorias
            t_start = datetime('07:55','InputFormat','HH:mm');
            t_start.Format = 'HH:mm';
            app.Graph_glicemia = table(145,t_start,'VariableNames',{'valor','data'});
            app.Graph_hc = table(0.0,t_start,'VariableNames',{'valor','data'});
            app.Graph_insulina = table(0.0,t_start,'VariableNames',{'valor','data'});
            
            
            % Lista de flags que permitem escolher qual o gráfico a apresentar 
            % ao utilizador e permitem fazer o zoom:
            app.zoom_on = false;
            app.Flag_graphs =[true, false, false];
            
            % Iniciar a variável com as flags que verificam o estado da
            % ligação bluetooth entre dispositivos:
            app.Flag_bluetooth = [false 0 false 0];
            app.Ack_sensor = false;       % Neste momento não estamos à espera de acknowledges
            app.Ack_bomba = false;            
            app.Debug_bomba = "";
            app.Debug_sensor = "";
            app.Max_erros = 0;
            
            % Inicializar variável para dar indicação sobre quando fazer a
            % renovação do sensor:
            app.Renovar_sensor = datetime('now').Day;
       
          
            % Valores iniciais do gráfico da Main mas que não são 
            % apresentados, e fazer o plot inicial:
            t1 = datetime('07:10','InputFormat','HH:mm');
            t2 = datetime('07:55','InputFormat','HH:mm'); 
            Horas = t1:minutes(5):t2;
            Horas = Horas';
            Horas.Format = 'HH:mm';
            avg = 145.* ones(10,1);
            Int = table(avg,Horas,'VariableNames',{'valor','data'});
            ploting(app, Int.data, Int.valor);
            
            
            % Variáveis que guardam toda a informação da comunicação entre dispositivos:
            app.Lista_msg = table([],[],[],[],[],[],[],'VariableNames',{'Tipo','ID','Assunto','Tamanho','Time','Msg_encript', 'Msg_decript'});
            app.Overall_msg = table("","","0","",'VariableNames',{'Num_msg','Time_inicio','Num_falhas','Time_ultima'});
            app.Overall_system = table("None","0",string(datestr(datetime('now'),'HH:MM')),"-1",'VariableNames',{'ID_simul','Num_simul','Time_limit','Num_renova'});


            % TEMPORÁRIO (buscar diretamente as informações do utilizador principal e atual,
            % sendo que isto deveria vir dos atributos: chave_privada, user: 
            
            %***********************
            % app.Chave_Privada = loadChavePrivada('PC', 1);
            % chave = loadChavePrivada('PC', 1);
            [app.Corrent_User, ~] = existeBD(user);
            % [app.Corrent_User, ~] = existeBD(chave);
            %************************
            
            [app.Main_User, ~] = existeBD(app.Chave_Privada);

            % Manter estes componentes desativados até ao estabelecimento
            % da comunicação entre dispositivos:
            app.GraphTypeDropDown.Enable = 'off';
            app.ZoominButton.Enable = 'off';
            app.ZoomoutButton.Enable = 'off';
            app.SendUpdateButton.Enable = 'off';
            app.Tree.Enable = 'off';  
            app.Image8.Visible = 'off';
            app.FreezeapplicationSwitch.Enable = 'off';
            app.LogoutButton.Enable = 'off';
            app.Image8.ImageSource = 'hjfj.gif';
            
            
            % Apresentar toda a informação do utilizador atual e do utilizador principal.
            % Definir também o nível de acesso de cada utilizador:
            app.NameEditField.Value = app.Corrent_User.utilizador_p.nome;
            app.BirthdayEditField.Value = datestr(app.Corrent_User.utilizador_p.dataNascimento);
            app.EmailEditField.Value = app.Corrent_User.utilizador_p.email;
            app.ContactEditField.Value = app.Corrent_User.utilizador_p.contacto;
            app.Image4.ImageSource = ['User',num2str(app.Corrent_User.id), '.jpg']; 
            
            if ~(app.Corrent_User.utilizador_p.nivel == 1)
                
                app.PatrciaSoaresLabel.Visible = 'off';
                app.GonaloBorgesLabel.Visible = 'off';
                app.GuilhermeRamosLabel.Visible = 'off';
                app.Image6.Visible = 'off';
                app.Image7.Visible = 'off';
                app.PrimaryuserLabel.Text = "Current user:";
                app.PermissionsLabel_2.Text = "Device owner:";
                app.Image5.ImageSource = "User1.jpg"; 
                app.LogoutButton.Enable = 'on';
                app.NameEditField_2.Value = app.Main_User.utilizador_p.nome;
                app.RelationEditField.Value = app.Main_User.utilizadorer_s.relacao(app.Main_User.utilizadorer_s.id_num == app.Corrent_User.id);
                app.ContactEditField_2.Value = app.Main_User.utilizador_p.contacto;
                
                try
                    load("fingerprint_ecriptacao_bd/BD/GG_saved.mat");
                    app.TituloGraphLabel.Text = "Glicemic Graph";
                    app.UIAxes2.YLim = [40 210];
                    app.Graph_glicemia = a;     
                    plot(app.UIAxes2,app.Graph_glicemia.data,app.Graph_glicemia.valor, 'MarkerEdgeColo',[0, 0, 0], 'MarkerSize',5, 'Marker', 'o','MarkerFaceColor',[1, 0, 0], 'Color', [0 0.7 0.8]);     
                    app.MaximumEditField_2.Value = max(app.Graph_glicemia.valor);
                    app.MinimumEditField_2.Value = min(app.Graph_glicemia.valor);
                    app.AverageEditField_2.Value = sum(app.Graph_glicemia.valor)/length(app.Graph_glicemia.valor);   
                    
                catch 
                    
                end
         
            
                if  strcmp(app.Main_User.utilizadorer_s.relacao(app.Main_User.utilizadorer_s.id_num == app.Corrent_User.id), 'Paciente')
                    app.DevicesMenu.Visible = 'off';
                    app.SendUpdateButton.Enable = 'off';
                    app.CarbohydratesEditField.Enable = 'off';
                else
                    app.MedicalRecordMenu.Visible = 'off';
                    app.DevicesMenu.Visible = 'off';                    
                end

                
            else
                app.NameLabel.Visible = 'off';
                app.ContactLabel.Visible = 'off';
                app.RelationLabel.Visible = 'off';
                app.NameEditField_2.Visible = 'off';
                app.RelationEditField.Visible = 'off';
                app.ContactEditField_2.Visible = 'off';
            end         


            % Apresentar toda a informação médica do utilizador principal:
            app.UITable3.Data = app.Main_User.tab_insulinaBasal;
            app.UITable3.ColumnName = {'Time', 'Insulin_Unit'};   
            app.UITable3_2.Data = app.Main_User.tab_insulinaHC;
            app.UITable3_2.ColumnName = {'Time', 'Unit_Carb'};
            app.InsulinsensitivityfactorEditField.Value = num2str(app.Main_User.tab_infoMedico.Sensibilidade);
            app.MaximumGlucoseLevelEditField.Value = num2str(app.Main_User.tab_infoMedico.g_max);
            app.MinimumGlucoseLevelEditField.Value = num2str(app.Main_User.tab_infoMedico.g_min);
                      
            
            % Identificar cada ramo da arvore, na parte do Back Panel: 
            app.ComunicationPanel.Visible = 'off';
            app.Image.Visible = 'off';
            app.MessageLabel.Text = "No Bluetooth connection" + newline + "between devices!";
            app.ReceiveNode.UserData = 'sr';
            app.SentNode.UserData = 'ss';
            app.ReceiveNode_2.UserData ='ir';
            app.SentNode_2.UserData ='is';
            
            
            % Create Timer object:
            app.ReadTimer = timer(...
                'ExecutionMode', 'fixedRate', ...       % Run timer repeatedly
                'Period', 3, ...                        % Period is 1 second
                'BusyMode', 'queue',...                 % Queue timer callbacks when busy
                'TimerFcn', @app.BluetoothTimerFcn);    % Specify callback function


        end

        % Key press function: UIFigure
        function UIFigureKeyPress(app, event)
            key = event.Key;
            
            if strcmp(key,'b')
                app.TabGroup.SelectedTab = app.Back_panelTab;
            end
            
        end

        % Menu selected function: DevicesMenu
        function DevicesMenuSelected(app, event)
            
            app.TabGroup.SelectedTab = app.DevicesTab;
            
        end

        % Cell selection callback: UITable
        function UITableCellSelection(app, event)
            indices = event.Indices;
            
            list = app.UITable.Data;
            msg = list{indices(1),1};  
            
            msg_encriptada = app.Lista_msg(strcmp(app.Lista_msg.ID,msg),6);
            msg_decriptada = app.Lista_msg(strcmp(app.Lista_msg.ID,msg),end);
            
            app.ListBox.Items = string(msg_encriptada.Msg_encript);
            app.ListBox_2.Items = string(msg_decriptada.Msg_decript);
                   
            
        end

        % Menu selected function: UsersMenu
        function UsersMenuSelected(app, event)
            
            app.TabGroup.SelectedTab = app.Personal_dataTab;
            
        end

        % Callback function: DevicesTab, HistoryMenu
        function HistoryMenuSelected(app, event)
            
            app.TabGroup.SelectedTab = app.HistoryTab;
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)

            stop(app.ReadTimer);
            delete(app.ReadTimer);
            delete(app);
            
        end

        % Menu selected function: HomeMenu
        function HomeMenuSelected(app, event)
            
            app.TabGroup.SelectedTab = app.MainTab;
            
            
        end

        % Value changed function: GraphTypeDropDown
        function GraphTypeDropDownValueChanged(app, event)
            value = app.GraphTypeDropDown.Value;
            
            app.Flag_graphs = [false, false, false];
            
            if strcmp(value,'Glycemic Graph')
                app.Flag_graphs(1) = true;
                
            elseif strcmp(value,'Insulin Graph')
                app.Flag_graphs(2) = true;
                
            else
                app.Flag_graphs(3) = true;
            end
            
        end

        % Button pushed function: ZoominButton
        function ZoominButtonPushed(app, event)
            
            value = app.GraphTypeDropDown.Value;
            app.zoom_on = true;
            
            a = app.StartEditField.Value;
            b = app.EditField_2.Value;
            c = app.FinishEditField.Value;
            d = app.EditField_3.Value;
            
            if (a >= 8 && a <=23) && (c >= a && c <=23) && (b >= 0 && b <=55) && (d >= 0 && d <=55)
                
                if strcmp(value,'Glycemic Graph')
                    
                    aux1 = duration(app.Graph_glicemia{:,2}.Hour, app.Graph_glicemia{:,2}.Minute, 0);
                    aux2 = duration(a,b,0);
                    aux3 = duration(app.Graph_glicemia{:,2}.Hour, app.Graph_glicemia{:,2}.Minute, 0);
                    aux4 = duration(c,d,0);
                    res = find(aux1 >= aux2  & aux3 <= aux4);
                    app.zoom_graph = app.Graph_glicemia(res(1):res(end),:); 
                    
                elseif strcmp(value,'Insulin Graph')
                    
                    aux1 = duration(app.Graph_insulina{:,2}.Hour, app.Graph_insulina{:,2}.Minute, 0);
                    aux2 = duration(a,b,0);
                    aux3 = duration(app.Graph_insulina{:,2}.Hour, app.Graph_insulina{:,2}.Minute, 0);
                    aux4 = duration(c,d,0);
                    res = find(aux1 >= aux2  & aux3 <= aux4);
                    app.zoom_graph = app.Graph_insulina(res(1):res(end),:);
                    
                else
                    
                    aux1 = duration(app.Graph_hc{:,2}.Hour, app.Graph_hc{:,2}.Minute, 0);
                    aux2 = duration(a,b,0);
                    aux3 = duration(app.Graph_hc{:,2}.Hour, app.Graph_hc{:,2}.Minute, 0);
                    aux4 = duration(c,d,0);
                    res = find(aux1 >= aux2  & aux3 <= aux4);
                    app.zoom_graph = app.Graph_hc(res(1):res(end),:); 
                    
                end       
            end

            
        end

        % Button pushed function: ZoomoutButton
        function ZoomoutButtonPushed(app, event)
            
            app.zoom_on = false;
            
        end

        % Menu selected function: MedicalRecordMenu
        function MedicalRecordMenuSelected(app, event)
            
            app.TabGroup.SelectedTab = app.Medical_recordTab;
            
        end

        % Cell selection callback: UITable3_2
        function UITable3_2CellSelection(app, event)
            app.indices_bolus = event.Indices;
            
            app.DoseTimeEditField_2.Value = app.UITable3_2.Data.Time(app.indices_bolus(1));
            app.InsulinunitsEditField_2.Value = num2str(app.UITable3_2.Data.Unit_Carb(app.indices_bolus(1)));    
 
            
        end

        % Cell selection callback: UITable3
        function UITable3CellSelection(app, event)
            app.indices_basal = event.Indices;
            
            app.DoseTimeEditField.Value = app.UITable3.Data.Time(app.indices_basal(1));
            app.InsulinunitsEditField.Value = app.UITable3.Data.Insulin_units(app.indices_basal(1));
            
        end

        % Button pushed function: ChangeButton
        function ChangeButtonPushed(app, event)
            
            t = app.DoseTimeEditField.Value;
            aux = app.InsulinunitsEditField.Value;
            
            try   
                datetime(t,'InputFormat', 'HH:mm');
                
                if any(str2double(aux))
                    d = str2double(aux);
                end
                
                app.UITable3.Data.Insulin_units(app.indices_basal(1)) = {d};
                app.UITable3.Data.Time(app.indices_basal(1)) = {t};   
            catch
                
            end
            
            app.Main_User.tab_insulinaBasal = app.UITable3.Data;
            
        end

        % Button pushed function: ChangeButton_2
        function ChangeButton_2Pushed(app, event)
            
            t = app.DoseTimeEditField_2.Value;
            aux = app.InsulinunitsEditField_2.Value;
            
            try   
                datetime(t,'InputFormat', 'HH:mm');
                
                if any(str2double(aux))
                    d = str2double(aux);
                end
                
                app.UITable3_2.Data.Unit_Carb(app.indices_bolus(1)) = {d};
                app.UITable3_2.Data.Time(app.indices_bolus(1)) = {t};  
            catch
                
            end
            
            app.Main_User.tab_insulinaHC = app.UITable3_2.Data;
            
        end

        % Button pushed function: SendUpdateButton
        function SendUpdateButtonPushed(app, event)
         
            assunto = "hc";
            value = app.CarbohydratesEditField.Value;

            % Guardar o novo valor dos hidratos de carbono na tabela "Graph_hc"
            app.Graph_hc(end,1) = {value};  
            
            
            % Informação sobre o hc e o tempo:
            value = string(value);
            time = app.Graph_hc{end,2};
 
            
            % Extrair o valor das unidades de hc a administrar:
            aux = datetime(app.Main_User.tab_insulinaHC.Time, 'InputFormat', 'HH:mm');
            index = aux < time;
            res = app.Main_User.tab_insulinaHC.Unit_Carb(index);
            unit_hc = res(end);
            
            
            % Ir buscar o valor da sensibilidade:
            sens = app.Main_User.tab_infoMedico.Sensibilidade;
            
            
            % Ir buscar o valor mais recente da glicemia:
            glicemia = string(num2str(app.Graph_glicemia{end,1}));
            
            
            % Encriptar + Guardar + Enviar a informação para a bomba:
            
            valores_decriptados = ["bolus", unit_hc, value, glicemia, sens];
            
            header = encripta(app.Chave_Privada,app.Chave_Publica_Bomba,"bolus");
            unit_hc = encripta(app.Chave_Privada,app.Chave_Publica_Bomba,unit_hc);
            hc = encripta(app.Chave_Privada,app.Chave_Publica_Bomba,value); 
            glicemia = encripta(app.Chave_Privada,app.Chave_Publica_Bomba,glicemia);
            sens = encripta(app.Chave_Privada,app.Chave_Publica_Bomba,sens);
            
            valores_encriptados = [header, unit_hc, hc, glicemia, sens];
            
            msg_size = [num2str(strlength(valores_decriptados(1))) ' x ' num2str(strlength(valores_decriptados(2))) ' x ' num2str(strlength(valores_decriptados(3))) ' x ' num2str(strlength(valores_decriptados(4))) ' x ' num2str(strlength(valores_decriptados(5)))]; 
            msg_size = string(msg_size); 
            
            time = datetime('now');
            time = string(datestr(time,"HH:MM"));           

            aux1 = table("is",string(str2num(app.Lista_msg{end,2})+1),valores_decriptados(1),msg_size,time,valores_encriptados,valores_decriptados,'VariableNames',{'Tipo','ID','Assunto','Tamanho','Time','Msg_encript', 'Msg_decript'});
            app.Lista_msg = [app.Lista_msg; aux1];
            
            app.Overall_msg = table(app.Lista_msg{end,2},app.Overall_msg{end,2},app.Overall_msg{end,3},app.Lista_msg{end,5},'VariableNames',{'Num_msg','Time_inicio','Num_falhas','Time_ultima'});     
                        
           
            %transmissor("app_bomba",valores_encriptados); 
            
            app.Ack_bomba = true;
            app.Debug_bomba = " from bolus";
            
            app.BluetoothTimerFcn();


        end

        % Button pushed function: ChangeButton_3
        function ChangeButton_3Pushed(app, event)
            
            try
                 
                new1 = str2num(app.InsulinsensitivityfactorEditField.Value);
                new2 = str2num(app.MaximumGlucoseLevelEditField.Value);
                new3 = str2num(app.MinimumGlucoseLevelEditField.Value);
                
                if new1
                   app.Main_User.tab_infoMedico.Sensibilidade = new1;
                end
                
                if new2
                   app.Main_User.tab_infoMedico.g_max = new2;
                end
                
                if new3
                   app.Main_User.tab_infoMedico.g_min = new3;
                end
                
            catch 
                
            end
            
            app.InsulinsensitivityfactorEditField.Value = num2str(app.Main_User.tab_infoMedico.Sensibilidade);
            app.MaximumGlucoseLevelEditField.Value = num2str(app.Main_User.tab_infoMedico.g_max);
            app.MinimumGlucoseLevelEditField.Value = num2str(app.Main_User.tab_infoMedico.g_min);
                  
            
        end

        % Value changed function: DefinethenewDateDatePicker
        function DefinethenewDateDatePickerValueChanged(app, event)
            
            app.Renovar_sensor = datetime('now').Day;
            
            value = app.DefinethenewDateDatePicker.Value;
            d =  value - datetime('now');

            
            try
                
                if ~(d <= 0)
                    aux = days(d);
                    aux = round(aux);
                    app.HowmanydaysleftEditField.Value = aux;
                    
                    if aux <= 1
                        app.SystemStateLabel.Text ="Buy New" + newline + "Warning!";
                        app.Image8.Visible = 'on';
                        app.Image9.ImageSource = 'source.gif';    
                        
                    end
                
                end
            
            catch 
                
            end
 
            
        end

        % Image clicked function: Image9
        function Image9Clicked(app, event)
            
            app.SystemStateLabel.Text = "System" + newline +  "State";
            app.Image8.Visible = 'off';
            app.Image9.ImageSource = 'ok.jpg';
           
            
        end

        % Image clicked function: Image9_2
        function Image9_2Clicked(app, event)
            
            app.SystemStateLabel_2.Text = "System" + newline +  "State";
            app.Image8.Visible = 'off';
            app.Image9_2.ImageSource = 'ok.jpg';            
            
        end

        % Selection changed function: Tree
        function TreeSelectionChanged(app, event)

            selectedNodes = app.Tree.SelectedNodes;


            if strcmp(selectedNodes.UserData,'sr')
                removeStyle(app.UITable);
                app.ComunicationPanel.Title = "Communication with Sensor";
                app.ComunicationPanel.Visible = 'on';
                app.Image10.Visible = 'off';
                app.Image.Visible = 'on';
                app.UhOhLabel.Visible = 'off';
                app.MessageLabel.Visible = 'off';
                
                % Higlight linhas com indicador de falha de mensagem
                list = app.Lista_msg(strcmp(app.Lista_msg.Tipo,"sr"),2:5);
                index = find(strcmp(list.Assunto,"MISSING"));
                index = index';
                
                if ~(isempty(index))
                    styling = uistyle;
                    styling.BackgroundColor = 'red';
                    addStyle(app.UITable,styling,'row',index);                        
                end

                app.UITable.Data = app.Lista_msg(strcmp(app.Lista_msg.Tipo,"sr"),2:5);
                app.UITable2_2.Data = app.Overall_msg;
                app.UITable2.Data = app.Overall_system;
                app.ListBox.Items = [""];
                app.ListBox_2.Items = [""];
                
            elseif strcmp(selectedNodes.UserData,'ss')
                removeStyle(app.UITable);
                app.ComunicationPanel.Title = "Communication with Sensor";
                app.ComunicationPanel.Visible = 'on';
                app.Image10.Visible = 'off';
                app.Image.Visible = 'on';
                app.UhOhLabel.Visible = 'off';
                
                % Higlight linhas com indicador de falha de mensagem
                list = app.Lista_msg(strcmp(app.Lista_msg.Tipo,"ss"),2:5);
                index = find(strcmp(list.Assunto,"MISSING"));
                index = index';
                
                if ~(isempty(index))
                    styling = uistyle;
                    styling.BackgroundColor = 'red';
                    addStyle(app.UITable,styling,'row',index);                        
                end
                
                app.UITable.Data = app.Lista_msg(strcmp(app.Lista_msg.Tipo,"ss"),2:5);
                app.UITable2_2.Data = app.Overall_msg;
                app.UITable2.Data = app.Overall_system;
                app.MessageLabel.Visible = 'off';
                app.ListBox.Items = [""];
                app.ListBox_2.Items = [""];
                
            elseif strcmp(selectedNodes.UserData,'ir')
                removeStyle(app.UITable);                
                app.ComunicationPanel.Title = "Communication with Insulin Pump"; 
                app.ComunicationPanel.Visible = 'on';
                app.Image10.Visible = 'off';
                app.Image.Visible = 'on';
                app.UhOhLabel.Visible = 'off';
                app.MessageLabel.Visible = 'off';   
                
                % Higlight linhas com indicador de falha de mensagem
                list = app.Lista_msg(strcmp(app.Lista_msg.Tipo,"ir"),2:5);
                index = find(strcmp(list.Assunto,"MISSING"));
                index = index';
                
                if ~(isempty(index))
                    styling = uistyle;
                    styling.BackgroundColor = 'red';
                    addStyle(app.UITable,styling,'row',index);                        
                end
                
                app.UITable.Data = app.Lista_msg(strcmp(app.Lista_msg.Tipo,"ir"),2:5);
                app.UITable2_2.Data = app.Overall_msg;
                app.UITable2.Data = app.Overall_system;
                app.ListBox.Items = [""];
                app.ListBox_2.Items = [""];                
                
            elseif strcmp(selectedNodes.UserData,'is')
                removeStyle(app.UITable);                   
                app.ComunicationPanel.Title = "Communication with Insulin Pump"; 
                app.ComunicationPanel.Visible = 'on';
                app.Image10.Visible = 'off';
                app.Image.Visible = 'on';
                app.UhOhLabel.Visible = 'off';
                app.MessageLabel.Visible = 'off';
                
                % Higlight linhas com indicador de falha de mensagem
                list = app.Lista_msg(strcmp(app.Lista_msg.Tipo,"is"),2:5);
                index = find(strcmp(list.Assunto,"MISSING"));
                index = index';
                
                if ~(isempty(index))
                    styling = uistyle;
                    styling.BackgroundColor = 'red';
                    addStyle(app.UITable,styling,'row',index);                        
                end                
                
                app.UITable.Data = app.Lista_msg(strcmp(app.Lista_msg.Tipo,"is"),2:5);
                app.UITable2_2.Data = app.Overall_msg;
                app.UITable2.Data = app.Overall_system;
                app.ListBox.Items = [""];
                app.ListBox_2.Items = [""];
                
            end    
            
            
        end

        % Image clicked function: Image8
        function Image8Clicked(app, event)
           
            app.Image8.Visible = 'off';
            app.TabGroup.SelectedTab = app.DevicesTab;
            
        end

        % Button pushed function: ActivateDevicesButton
        function ActivateDevicesButtonPushed(app, event)
            
            app.Flag_bluetooth = [false 0 false 0];
            app.Chave_Publica_Sensor = chavePublica();
            app.Chave_Publica_Bomba = chavePublica();
            
            app.PrivateKeyEditField.Value = app.Chave_Privada;
            app.PumpPublicKeyEditField.Value = app.Chave_Publica_Bomba;
            app.SensorPublicKeyEditField.Value = app.Chave_Publica_Sensor;
            
            data_renova = datestr(datetime('now')+ hours(5),'HH:MM');
            data_renova = string(data_renova);
            app.Overall_system = table(app.Overall_system{1,1},app.Overall_system{1,2},data_renova,string(str2num(app.Overall_system{1,4})+1),'VariableNames',{'ID_simul','Num_simul','Time_limit','Num_renova'});
            
            
            a = randi(30);
            b = randi(30);
            c = randi(30);
            d = randi(30);
            app.Flag_bluetooth(2) = a * b;
            app.Flag_bluetooth(4) = c * d;

            assunto = "autenticacao";
            
            info1 = [num2str(a) '*' num2str(b)];     % Para obter o resultado, basta fazer algo como str2num('30*29');
            info2 = [num2str(c) '*' num2str(d)];
            
            encrip_info_sensor = encripta(app.Chave_Privada, app.Chave_Publica_Sensor, info1);
            encrip_assunto_sensor = encripta(app.Chave_Privada, app.Chave_Publica_Sensor, assunto);
            encrip_info_bomba = encripta(app.Chave_Privada, app.Chave_Publica_Bomba, info2);
            encrip_assunto_bomba = encripta(app.Chave_Privada, app.Chave_Publica_Bomba, assunto);
          
            conteudo_sensor = [encrip_assunto_sensor, app.Chave_Publica_Sensor, encrip_info_sensor];
            conteudo_bomba = [encrip_assunto_bomba, app.Chave_Publica_Bomba, encrip_info_bomba];
           
            %transmissor("app_bomba",conteudo_bomba);
            %transmissor("app_sensor",conteudo_sensor);
            
            msg_sensor_size = [num2str(strlength(assunto)) ' x ' num2str(strlength(app.Chave_Publica_Sensor)) ' x ' num2str(strlength(info1))]; 
            msg_sensor_size = string(msg_sensor_size);
            
            msg_pump_size = [num2str(strlength(assunto)) ' x ' num2str(strlength(app.Chave_Publica_Bomba)) ' x ' num2str(strlength(info2))]; 
            msg_pump_size = string(msg_pump_size);
            
            time = datetime('now');
            time = string(datestr(time,"HH:MM"));
            
            conteudo_sensor = [encrip_assunto_sensor, app.Chave_Publica_Sensor, encrip_info_sensor, "", ""];
            conteudo_bomba = [encrip_assunto_bomba, app.Chave_Publica_Bomba, encrip_info_bomba, "", ""];            
            msg_decriptada_sensor = [assunto,app.Chave_Publica_Sensor,info1, "", ""];
            msg_decriptada_bomba = [assunto,app.Chave_Publica_Bomba,info2, "", ""];
            
            
            if isempty(app.Lista_msg)
                id = "1";
                time_overall = string(datestr(datetime('now'),"HH:MM"));
            else
                id = string(str2num(app.Lista_msg{end,2})+1);
                time_overall = app.Overall_msg{end,2};
            end
            
            aux1 = table("ss",id,"Autenticação",msg_sensor_size,time,conteudo_sensor,msg_decriptada_sensor,'VariableNames',{'Tipo','ID','Assunto','Tamanho','Time','Msg_encript', 'Msg_decript'});
            app.Lista_msg = [app.Lista_msg; aux1];
            aux2 = table("is",string(str2num(app.Lista_msg{end,2})+1),"Autenticação",msg_pump_size,time,conteudo_bomba,msg_decriptada_bomba,'VariableNames',{'Tipo','ID','Assunto','Tamanho','Time','Msg_encript', 'Msg_decript'});
            app.Lista_msg = [app.Lista_msg; aux2];
            
            app.Overall_msg = table(app.Lista_msg{end,2},time_overall,app.Overall_msg{end,3},app.Lista_msg{end,5},'VariableNames',{'Num_msg','Time_inicio','Num_falhas','Time_ultima'});  
           
                  
            % TEMPORÁRIO:      
            app.Image10.ImageSource = "chnv.gif";       
            app.UhOhLabel.Text = "Ready!";
            app.MessageLabel.Text = "Connection established" + newline + "between devices!";
            app.Tree.Enable = 'on';
            app.GraphTypeDropDown.Enable = 'on';
            app.ZoominButton.Enable = 'on';
            app.ZoomoutButton.Enable = 'on';
            app.FreezeapplicationSwitch.Enable = 'on';
            app.SendUpdateButton.Enable = 'on'; 
            app.LogoutButton.Enable = 'on';
            
            % Ativar Timer:
            if strcmp(app.ReadTimer.Running, 'off')
               start(app.ReadTimer);
            end
            
            app.ActivateDevicesButton.Enable = 'off';
 
            
        end

        % Value changed function: ListBox
        function ListBoxValueChanged(app, event)
            
            value = app.ListBox.Value;
            
            s = app.ListBox.Items;
            index = ismember(s,value);
            
            item = app.ListBox_2.Items(index);
            
            app.ListBox_2.Value = item{1};
            
        end

        % Value changed function: ListBox_2
        function ListBox_2ValueChanged(app, event)
            value = app.ListBox_2.Value;
            
            s = app.ListBox_2.Items;
            index = ismember(s,value);
            
            item = app.ListBox.Items(index);
            
            app.ListBox.Value = item{1};
            
        end

        % Value changed function: FreezeapplicationSwitch
        function FreezeapplicationSwitchValueChanged(app, event)
            value = app.FreezeapplicationSwitch.Value;
            
            
            if strcmp(value, 'Off')
               start(app.ReadTimer);
               app.SendUpdateButton.Enable = 'on';
               app.ChangeButton_3.Enable = 'on';
               app.ChangeButton.Enable = 'on';
               app.ChangeButton_2.Enable = 'on';
               app.ZoominButton.Enable = 'on';
               app.ZoomoutButton.Enable = 'on';
               app.GraphTypeDropDown.Enable = 'on';
            else
               stop(app.ReadTimer);
               app.SendUpdateButton.Enable = 'off';
               app.ChangeButton_3.Enable = 'off';
               app.ChangeButton.Enable = 'off';
               app.ChangeButton_2.Enable = 'off';
               app.ZoominButton.Enable = 'off';
               app.ZoomoutButton.Enable = 'off';
               app.GraphTypeDropDown.Enable = 'off';
            end            
            
            
        end

        % Button pushed function: LogoutButton
        function LogoutButtonPushed(app, event)
           
            a = app.Graph_glicemia;

            save('fingerprint_ecriptacao_bd/BD/GG_saved.mat','a');
   
            stop(app.ReadTimer);
            delete(app.ReadTimer);
            app.LogoutButton.Enable = 'off';
            pause(1);
            
            app_begin(app);
            delete(app);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.KeyPressFcn = createCallbackFcn(app, @UIFigureKeyPress, true);
            app.HomeMenu = uimenu(app.UIFigure);
            app.HomeMenu.MenuSelectedFcn = createCallbackFcn(app, @HomeMenuSelected, true);
            app.HomeMenu.Text = 'Home';
            app.UsersMenu = uimenu(app.UIFigure);
            app.UsersMenu.MenuSelectedFcn = createCallbackFcn(app, @UsersMenuSelected, true);
            app.UsersMenu.Text = 'Users';
            app.MedicalRecordMenu = uimenu(app.UIFigure);
            app.MedicalRecordMenu.MenuSelectedFcn = createCallbackFcn(app, @MedicalRecordMenuSelected, true);
            app.MedicalRecordMenu.Text = 'Medical Record';
            app.DevicesMenu = uimenu(app.UIFigure);
            app.DevicesMenu.MenuSelectedFcn = createCallbackFcn(app, @DevicesMenuSelected, true);
            app.DevicesMenu.Text = 'Devices';
            app.HistoryMenu = uimenu(app.UIFigure);
            app.HistoryMenu.MenuSelectedFcn = createCallbackFcn(app, @HistoryMenuSelected, true);
            app.HistoryMenu.Text = 'History';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.BloodGlucoseLevelsLabel = uilabel(app.MainTab);
            app.BloodGlucoseLevelsLabel.Text = 'Blood Glucose Levels';
            app.UIAxes = uiaxes(app.MainTab);
            title(app.UIAxes, '')
            xlabel(app.UIAxes, '')
            ylabel(app.UIAxes, '')
            app.AverageEditFieldLabel = uilabel(app.MainTab);
            app.AverageEditFieldLabel.Text = 'Average';
            app.AverageEditField = uieditfield(app.MainTab, 'numeric');
            app.LatestValueEditField = uieditfield(app.MainTab, 'numeric');
            app.LatestValueEditFieldLabel = uilabel(app.MainTab);
            app.LatestValueEditFieldLabel.Text = 'Latest Value';
            app.Image8 = uiimage(app.MainTab);
            app.Image8.ImageClickedFcn = createCallbackFcn(app, @Image8Clicked, true);
            app.Image8.ImageSource = 'null.png';
            app.LogoutButton = uibutton(app.MainTab, 'push');
            app.LogoutButton.ButtonPushedFcn = createCallbackFcn(app, @LogoutButtonPushed, true);
            app.LogoutButton.Text = 'Logout';
            app.Back_panelTab = uitab(app.TabGroup);
            app.Back_panelTab.Title = 'Back_panel';
            app.BackPanelLabel = uilabel(app.Back_panelTab);
            app.BackPanelLabel.Text = 'Back Panel';
            app.ComunicationPanel = uipanel(app.Back_panelTab);
            app.ComunicationPanel.Title = 'Comunication';
            app.UITable = uitable(app.ComunicationPanel);
            app.UITable.ColumnName = {'ID'; 'Subject'; 'Size'; 'Time'};
            app.UITable.RowName = {};
            app.UITable.CellSelectionCallback = createCallbackFcn(app, @UITableCellSelection, true);
            app.PrivateKeyLabel = uilabel(app.ComunicationPanel);
            app.PrivateKeyLabel.Text = 'Private Key:';
            app.PrivateKeyEditField = uieditfield(app.ComunicationPanel, 'text');
            app.PumpPublicKeyEditFieldLabel = uilabel(app.ComunicationPanel);
            app.PumpPublicKeyEditFieldLabel.Text = 'Pump Public Key:';
            app.PumpPublicKeyEditField = uieditfield(app.ComunicationPanel, 'text');
            app.MessageListLabel = uilabel(app.ComunicationPanel);
            app.MessageListLabel.Text = 'Message List';
            app.UITable2 = uitable(app.ComunicationPanel);
            app.UITable2.ColumnName = {'ID'; 'Nº Simulations'; 'Time Limit'; 'Nº Renewals'};
            app.UITable2.RowName = {};
            app.UITable2_2 = uitable(app.ComunicationPanel);
            app.UITable2_2.ColumnName = {'Nº Messages'; 'Start Time'; 'Nº Missing'; 'Last Time'};
            app.UITable2_2.RowName = {};
            app.SensorPublicKeyEditFieldLabel = uilabel(app.ComunicationPanel);
            app.SensorPublicKeyEditFieldLabel.Text = 'Sensor Public Key:';
            app.SensorPublicKeyEditField = uieditfield(app.ComunicationPanel, 'text');
            app.ListBox = uilistbox(app.ComunicationPanel);
            app.ListBox.Items = {'', '', '', ''};
            app.ListBox.ValueChangedFcn = createCallbackFcn(app, @ListBoxValueChanged, true);
            app.ListBox.Value = '';
            app.ListBox_2 = uilistbox(app.ComunicationPanel);
            app.ListBox_2.Items = {'', '', '', ''};
            app.ListBox_2.ValueChangedFcn = createCallbackFcn(app, @ListBox_2ValueChanged, true);
            app.ListBox_2.Value = '';
            app.DecryptedMessageLabel = uilabel(app.ComunicationPanel);
            app.DecryptedMessageLabel.Text = {'Decrypted'; 'Message'};
            app.EncryptedMessageLabel = uilabel(app.ComunicationPanel);
            app.EncryptedMessageLabel.Text = {'Encrypted'; 'Message'};
            app.Tree = uitree(app.Back_panelTab);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @TreeSelectionChanged, true);
            app.CommunicationNode = uitreenode(app.Tree);
            app.CommunicationNode.Text = 'Communication';
            app.SensorNode = uitreenode(app.CommunicationNode);
            app.SensorNode.Text = 'Sensor';
            app.ReceiveNode = uitreenode(app.SensorNode);
            app.ReceiveNode.Text = 'Receive';
            app.SentNode = uitreenode(app.SensorNode);
            app.SentNode.Text = 'Sent';
            app.InsulinPumpNode = uitreenode(app.CommunicationNode);
            app.InsulinPumpNode.Text = 'Insulin Pump';
            app.ReceiveNode_2 = uitreenode(app.InsulinPumpNode);
            app.ReceiveNode_2.Text = 'Receive';
            app.SentNode_2 = uitreenode(app.InsulinPumpNode);
            app.SentNode_2.Text = 'Sent';
            app.Image = uiimage(app.Back_panelTab);
            app.Image.ImageSource = 'yyyyych.jpg';
            app.UhOhLabel = uilabel(app.Back_panelTab);
            app.UhOhLabel.Text = 'Uh-Oh!';
            app.MessageLabel = uilabel(app.Back_panelTab);
            app.MessageLabel.Text = 'Message';
            app.Image10 = uiimage(app.Back_panelTab);
            app.Image10.ImageSource = 'xxx.gif';
            app.FreezeapplicationSwitchLabel = uilabel(app.Back_panelTab);
            app.FreezeapplicationSwitchLabel.Text = 'Freeze application';
            app.FreezeapplicationSwitch = uiswitch(app.Back_panelTab, 'slider');
            app.FreezeapplicationSwitch.ValueChangedFcn = createCallbackFcn(app, @FreezeapplicationSwitchValueChanged, true);
            app.Personal_dataTab = uitab(app.TabGroup);
            app.Personal_dataTab.Title = 'Personal_data';
            app.PersonalDataLabel = uilabel(app.Personal_dataTab);
            app.PersonalDataLabel.Text = 'Personal Data';
            app.NameEditFieldLabel = uilabel(app.Personal_dataTab);
            app.NameEditFieldLabel.Text = 'Name';
            app.NameEditField = uieditfield(app.Personal_dataTab, 'text');
            app.BirthdayEditFieldLabel = uilabel(app.Personal_dataTab);
            app.BirthdayEditFieldLabel.Text = 'Birthday';
            app.BirthdayEditField = uieditfield(app.Personal_dataTab, 'text');
            app.EmailEditFieldLabel = uilabel(app.Personal_dataTab);
            app.EmailEditFieldLabel.Text = 'E-mail';
            app.EmailEditField = uieditfield(app.Personal_dataTab, 'text');
            app.ContactEditFieldLabel = uilabel(app.Personal_dataTab);
            app.ContactEditFieldLabel.Text = 'Contact';
            app.ContactEditField = uieditfield(app.Personal_dataTab, 'text');
            app.Image4 = uiimage(app.Personal_dataTab);
            app.Image4.ImageSource = 'User1.jpg';
            app.PrimaryuserLabel = uilabel(app.Personal_dataTab);
            app.PrimaryuserLabel.Text = 'Primary user:';
            app.PermissionsLabel_2 = uilabel(app.Personal_dataTab);
            app.PermissionsLabel_2.Text = 'Permissions:';
            app.Image5 = uiimage(app.Personal_dataTab);
            app.Image5.ImageSource = 'User2.jpg';
            app.Image6 = uiimage(app.Personal_dataTab);
            app.Image6.ImageSource = 'User3.jpg';
            app.Image7 = uiimage(app.Personal_dataTab);
            app.Image7.ImageSource = 'User4.jpg';
            app.GuilhermeRamosLabel = uilabel(app.Personal_dataTab);
            app.GuilhermeRamosLabel.Text = 'Guilherme Ramos';
            app.PatrciaSoaresLabel = uilabel(app.Personal_dataTab);
            app.PatrciaSoaresLabel.Text = 'Patrícia Soares';
            app.GonaloBorgesLabel = uilabel(app.Personal_dataTab);
            app.GonaloBorgesLabel.Text = 'Gonçalo Borges ';
            app.NameEditField_2 = uieditfield(app.Personal_dataTab, 'text');
            app.ContactEditField_2 = uieditfield(app.Personal_dataTab, 'text');
            app.RelationEditField = uieditfield(app.Personal_dataTab, 'text');
            app.NameLabel = uilabel(app.Personal_dataTab);
            app.NameLabel.Text = 'Name';
            app.RelationLabel = uilabel(app.Personal_dataTab);
            app.RelationLabel.Text = 'Relation';
            app.ContactLabel = uilabel(app.Personal_dataTab);
            app.ContactLabel.Text = 'Contact';
            app.Medical_recordTab = uitab(app.TabGroup);
            app.Medical_recordTab.Title = 'Medical_record';
            app.CarbohydratesEditFieldLabel = uilabel(app.Medical_recordTab);
            app.CarbohydratesEditFieldLabel.Text = 'Carbohydrates:';
            app.CarbohydratesEditField = uieditfield(app.Medical_recordTab, 'numeric');
            app.SendUpdateButton = uibutton(app.Medical_recordTab, 'push');
            app.SendUpdateButton.ButtonPushedFcn = createCallbackFcn(app, @SendUpdateButtonPushed, true);
            app.SendUpdateButton.Text = 'Send Update';
            app.MedicalRecommendationsforInsulinInjectionLabel = uilabel(app.Medical_recordTab);
            app.MedicalRecommendationsforInsulinInjectionLabel.Text = {'Medical Recommendations for'; 'Insulin Injection '};
            app.TabGroup2 = uitabgroup(app.Medical_recordTab);
            app.BasalInjectionsTab = uitab(app.TabGroup2);
            app.BasalInjectionsTab.Title = 'Basal Injections';
            app.UITable3 = uitable(app.BasalInjectionsTab);
            app.UITable3.ColumnName = {'Time'; 'Insulin_Unit'};
            app.UITable3.RowName = {};
            app.UITable3.CellSelectionCallback = createCallbackFcn(app, @UITable3CellSelection, true);
            app.DoseTimeLabel = uilabel(app.BasalInjectionsTab);
            app.DoseTimeLabel.Text = ' Dose Time:';
            app.DoseTimeEditField = uieditfield(app.BasalInjectionsTab, 'text');
            app.InsulinunitsLabel = uilabel(app.BasalInjectionsTab);
            app.InsulinunitsLabel.Text = 'Insulin units:';
            app.InsulinunitsEditField = uieditfield(app.BasalInjectionsTab, 'text');
            app.ChangeButton = uibutton(app.BasalInjectionsTab, 'push');
            app.ChangeButton.ButtonPushedFcn = createCallbackFcn(app, @ChangeButtonPushed, true);
            app.ChangeButton.Text = 'Change';
            app.BolusInjectionsTab = uitab(app.TabGroup2);
            app.BolusInjectionsTab.Title = 'Bolus Injections';
            app.ChangeButton_2 = uibutton(app.BolusInjectionsTab, 'push');
            app.ChangeButton_2.ButtonPushedFcn = createCallbackFcn(app, @ChangeButton_2Pushed, true);
            app.ChangeButton_2.Text = 'Change';
            app.DoseTimeEditField_2Label = uilabel(app.BolusInjectionsTab);
            app.DoseTimeEditField_2Label.Text = ' Dose Time:';
            app.DoseTimeEditField_2 = uieditfield(app.BolusInjectionsTab, 'text');
            app.InsulinunitsEditField_2Label = uilabel(app.BolusInjectionsTab);
            app.InsulinunitsEditField_2Label.Text = 'Insulin units:';
            app.InsulinunitsEditField_2 = uieditfield(app.BolusInjectionsTab, 'text');
            app.UITable3_2 = uitable(app.BolusInjectionsTab);
            app.UITable3_2.ColumnName = {'Time'; 'Unit_Carb'};
            app.UITable3_2.RowName = {};
            app.UITable3_2.CellSelectionCallback = createCallbackFcn(app, @UITable3_2CellSelection, true);
            app.MedicalstatementsLabel = uilabel(app.Medical_recordTab);
            app.MedicalstatementsLabel.Text = 'Medical statements:';
            app.MinimumGlucoseLevelEditFieldLabel = uilabel(app.Medical_recordTab);
            app.MinimumGlucoseLevelEditFieldLabel.Text = 'Minimum Glucose Level';
            app.MinimumGlucoseLevelEditField = uieditfield(app.Medical_recordTab, 'text');
            app.MaximumGlucoseLevelEditFieldLabel = uilabel(app.Medical_recordTab);
            app.MaximumGlucoseLevelEditFieldLabel.Text = 'Maximum Glucose Level';
            app.MaximumGlucoseLevelEditField = uieditfield(app.Medical_recordTab, 'text');
            app.InsulinsensitivityfactorEditFieldLabel = uilabel(app.Medical_recordTab);
            app.InsulinsensitivityfactorEditFieldLabel.Text = 'Insulin sensitivity factor';
            app.InsulinsensitivityfactorEditField = uieditfield(app.Medical_recordTab, 'text');
            app.ChangeButton_3 = uibutton(app.Medical_recordTab, 'push');
            app.ChangeButton_3.ButtonPushedFcn = createCallbackFcn(app, @ChangeButton_3Pushed, true);
            app.ChangeButton_3.Text = 'Change';
            app.MedicalRecordsLabel = uilabel(app.Medical_recordTab);
            app.MedicalRecordsLabel.Text = 'Medical Records';
            app.DevicesTab = uitab(app.TabGroup);
            app.DevicesTab.SizeChangedFcn = createCallbackFcn(app, @HistoryMenuSelected, true);
            app.DevicesTab.Title = 'Devices';
            app.ConnectingDevicesLabel = uilabel(app.DevicesTab);
            app.ConnectingDevicesLabel.Text = 'Connecting Devices';
            app.CommunicationwithInsulinPumpPanel = uipanel(app.DevicesTab);
            app.CommunicationwithInsulinPumpPanel.Title = 'Communication with Insulin Pump';
            app.Image2_2 = uiimage(app.CommunicationwithInsulinPumpPanel);
            app.Image2_2.ImageSource = '16g.png';
            app.SystemStateLabel_2 = uilabel(app.CommunicationwithInsulinPumpPanel);
            app.SystemStateLabel_2.Text = {'System'; 'State'};
            app.Image9_2 = uiimage(app.CommunicationwithInsulinPumpPanel);
            app.Image9_2.ImageClickedFcn = createCallbackFcn(app, @Image9_2Clicked, true);
            app.Image9_2.ImageSource = 'ok.jpg';
            app.DepositLevelGauge_2Label = uilabel(app.CommunicationwithInsulinPumpPanel);
            app.DepositLevelGauge_2Label.Text = 'Deposit Level';
            app.DepositLevelGauge_2 = uigauge(app.CommunicationwithInsulinPumpPanel, 'linear');
            app.DepositLevelGauge_2.Limits = [0 300];
            app.LatestadministeredInsulindoseEditFieldLabel = uilabel(app.CommunicationwithInsulinPumpPanel);
            app.LatestadministeredInsulindoseEditFieldLabel.Text = {'Latest administered '; 'Insulin dose:'};
            app.LatestadministeredInsulindoseEditField = uieditfield(app.CommunicationwithInsulinPumpPanel, 'numeric');
            app.CommunicationwithSensorPanel = uipanel(app.DevicesTab);
            app.CommunicationwithSensorPanel.Title = 'Communication with Sensor';
            app.DefinethenewDateDatePickerLabel = uilabel(app.CommunicationwithSensorPanel);
            app.DefinethenewDateDatePickerLabel.Text = {'Define the '; 'new Date'};
            app.DefinethenewDateDatePicker = uidatepicker(app.CommunicationwithSensorPanel);
            app.DefinethenewDateDatePicker.ValueChangedFcn = createCallbackFcn(app, @DefinethenewDateDatePickerValueChanged, true);
            app.Image2 = uiimage(app.CommunicationwithSensorPanel);
            app.Image2.ImageSource = '5.png';
            app.HowmanydaysleftEditFieldLabel = uilabel(app.CommunicationwithSensorPanel);
            app.HowmanydaysleftEditFieldLabel.Text = {'How many '; 'days left '};
            app.HowmanydaysleftEditField = uieditfield(app.CommunicationwithSensorPanel, 'numeric');
            app.SystemStateLabel = uilabel(app.CommunicationwithSensorPanel);
            app.SystemStateLabel.Text = {'System'; 'State'};
            app.Image9 = uiimage(app.CommunicationwithSensorPanel);
            app.Image9.ImageClickedFcn = createCallbackFcn(app, @Image9Clicked, true);
            app.Image9.ImageSource = 'ok.jpg';
            app.ActivateDevicesButton = uibutton(app.DevicesTab, 'push');
            app.ActivateDevicesButton.ButtonPushedFcn = createCallbackFcn(app, @ActivateDevicesButtonPushed, true);
            app.ActivateDevicesButton.Text = {'Activate'; 'Devices '};
            app.HistoryTab = uitab(app.TabGroup);
            app.HistoryTab.Title = 'History';
            app.TituloGraphLabel = uilabel(app.HistoryTab);
            app.TituloGraphLabel.Text = 'Glycemic Graph';
            app.UIAxes2 = uiaxes(app.HistoryTab);
            title(app.UIAxes2, '')
            xlabel(app.UIAxes2, 'Time')
            ylabel(app.UIAxes2, '')
            app.StartEditFieldLabel = uilabel(app.HistoryTab);
            app.StartEditFieldLabel.Text = 'Start';
            app.StartEditField = uieditfield(app.HistoryTab, 'numeric');
            app.EditField_2 = uieditfield(app.HistoryTab, 'numeric');
            app.Label = uilabel(app.HistoryTab);
            app.Label.Text = ':';
            app.EditField_3 = uieditfield(app.HistoryTab, 'numeric');
            app.Label_2 = uilabel(app.HistoryTab);
            app.Label_2.Text = ':';
            app.FinishEditFieldLabel = uilabel(app.HistoryTab);
            app.FinishEditFieldLabel.Text = 'Finish';
            app.FinishEditField = uieditfield(app.HistoryTab, 'numeric');
            app.DefineanIntervalLabel = uilabel(app.HistoryTab);
            app.DefineanIntervalLabel.Text = 'Define an Interval:';
            app.ZoominButton = uibutton(app.HistoryTab, 'push');
            app.ZoominButton.ButtonPushedFcn = createCallbackFcn(app, @ZoominButtonPushed, true);
            app.ZoominButton.Text = 'Zoom in';
            app.AverageEditField_2Label = uilabel(app.HistoryTab);
            app.AverageEditField_2Label.Text = 'Average:';
            app.AverageEditField_2 = uieditfield(app.HistoryTab, 'numeric');
            app.GraphTypeDropDownLabel = uilabel(app.HistoryTab);
            app.GraphTypeDropDownLabel.Text = 'Graph Type:';
            app.GraphTypeDropDown = uidropdown(app.HistoryTab);
            app.GraphTypeDropDown.Items = {'Glycemic Graph', 'Insulin Graph', 'Crabohydrates Graph'};
            app.GraphTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @GraphTypeDropDownValueChanged, true);
            app.GraphTypeDropDown.Value = 'Glycemic Graph';
            app.PeakvaluesLabel = uilabel(app.HistoryTab);
            app.PeakvaluesLabel.Text = 'Peak values:';
            app.MaximumEditField_2Label = uilabel(app.HistoryTab);
            app.MaximumEditField_2Label.Text = 'Maximum';
            app.MaximumEditField_2 = uieditfield(app.HistoryTab, 'numeric');
            app.MinimumEditField_2Label = uilabel(app.HistoryTab);
            app.MinimumEditField_2Label.Text = 'Minimum';
            app.MinimumEditField_2 = uieditfield(app.HistoryTab, 'numeric');
            app.ZoomoutButton = uibutton(app.HistoryTab, 'push');
            app.ZoomoutButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomoutButtonPushed, true);
            app.ZoomoutButton.Text = 'Zoom out';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end